classdef CoronalLabelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        AddLinesButton         matlab.ui.control.Button
        LoadNewPatientButton   matlab.ui.control.Button
        ResetLinesButton       matlab.ui.control.Button
        SaveDataButton         matlab.ui.control.Button
        CurrentPatientIDLabel  matlab.ui.control.Label
        CurrentPatientLabel    matlab.ui.control.Label
    end

    properties (Access = public)
        info % Dicom info
        img % Set of mri slices, taken in transverse plane
        mn = 1 % Minimum Position
        mx1 % Max of Dimension 1
        scaling = 2 % Scales the size of the image
        n = 5 % Number of lines to be set
        k = 0 % Number of clicks 
        sliceIdx % Index of the traverse slice that was clicked
        slice1 % Index of the displayed coronal slice in f1 
        p % Plotted lines
        res % Position of the landmarks and selected patient data
        f1 % Figure 1, container in which all operations are performed
        i1 % Handle of the currently displayed image
        fullFile % Fullpath of the opened file
        openByScript = 1 % For opening and closing files automatically by using RunGUIScript.m
    end

    methods (Access = private)
        
        % Evaluates whether the cursorPositon cP was on image during click
        function state = on_image(app, cP)
            state = 0;
            
            if cP(1,1) < 1 || cP(1,1) > size(app.i1.CData,2)
                uialert(app.UIFigure,'Correct horizontal position of mouse','Out of Image');
                return
            elseif cP(1,2) < 1 || cP(1,2) > size(app.i1.CData,1)
                uialert(app.UIFigure,'Correct vertical position of mouse','Out of Image');
                return
            else 
                state = 1;
                return 
            end
        end
    
        % Calculates the global coordinats from the slice positions
        function b = global_coordinates(app)
            nSlices = size(app.img, 3);
    
            dOrient = reshape(app.info.ImageOrientationPatient, [3, 2])';
            dPixelSpacing = app.info.PixelSpacing;
            dPosition = zeros(nSlices, 3);
            
            % Set position of first slice for all
            for iI = 1:nSlices 
                dPosition(iI, :) = app.info.ImagePositionPatient';
            end
            % Set slice position
%             SCoord.sOrientation = 'Tra';
%             SCoord.dLR = (dPosition(1, 1) + dOrient(1, 1).*(0:size(app.img, 2) - 1).*dPixelSpacing(1))';
%             SCoord.dAP = (dPosition(1, 2) + dOrient(2, 2).*(0:size(app.img, 1) - 1).*dPixelSpacing(2))';
            SCoord.dFH = (dPosition(1, 3) + (0:size(app.img, 3)-1).*app.info.SpacingBetweenSlices)';    
            b = (dPosition(1, 3) + (0:size(app.img, 3)-1).*app.info.SpacingBetweenSlices)';    
            
            return
        end
    
        % Reevaluates the changing slice1, changes plotted slice
        function re_eval1(app)
            if app.slice1>app.mx1
                app.slice1=app.mx1;
            elseif app.slice1<app.mn
                app.slice1=app.mn;
            else
                set(app.i1,'CData',imresize(imrotate(squeeze(app.img(app.slice1,:,:)), 90),app.scaling))
            end
        end

        % Checks whether n lines have been drawn
        % Checks whether the sliceIdx were created in order
        function plausible = reasonable_res(app)
            plausible = 0;
            
            if app.k < app.n
                uialert(app.UIFigure,'Not enough Lines','Invalid Data');
                return                
            elseif (max(diff(app.sliceIdx))>= 0)
                uialert(app.UIFigure,'Lines not in order. \n Start from wrist to heel.','Invalid Data');
                return
            else 
                plausible = 1;
                return
            end
        end
    
        % Collects all data that needs to be saved
        function create_res(app)
            app.res.wrist = app.sliceIdx(1);
            app.res.shoulder = app.sliceIdx(2);
            app.res.heartEnd = app.sliceIdx(3);
            app.res.hip = app.sliceIdx(4);
            app.res.heel = app.sliceIdx(5);
            app.res.ID = app.info.PatientID;
            app.res.sex = app.info.PatientSex;
            if isfield(app.info, 'PatientSize')
                app.res.size = app.info.PatientSize;
            else
                app.res.size = nan;
            end
            app.res.weight = app.info.PatientWeight;
            gc = global_coordinates(app);
            for i=1:app.n
                app.res.globalCoordinates(i)  = gc(app.sliceIdx(i));
            end
        end
    end

    methods (Access = private)

        % Button pushed function: LoadNewPatientButton
        function LoadNewPatientButtonPushed(app, event)
            if app.openByScript
                % get folder from current matlab workspace
                pathName = evalin('base', 'currentPath');
                fileName = evalin('base', 'currentName');
            else
                % open UI to select folder  
                data_dir = '/home/s1283/no_backup/s1283/data';
                [fileName, pathName] = uigetfile({'*.mat;' 'Matlab Data file';},'Select a file',data_dir);
            end            


            app.fullFile = fullfile(pathName, fileName);

            tmp = load(app.fullFile, 'info', 'img');

            app.info = tmp.info;
            app.img = tmp.img;
            
            app.CurrentPatientIDLabel.Text = app.info.PatientID;
            app.mx1 = size(app.img, 1);
            
            app.f1 = figure(1);
            app.slice1 = floor(size(app.img, 1)/2);
            
            app.i1=imshow(imresize(imrotate(squeeze(app.img(app.slice1,:,:)), 90),app.scaling),'parent',gca);
            set(gca,'DataAspectRatio',[app.info.SpacingBetweenSlices/app.info.PixelSpacing(1) 1 1]);
            set(app.f1, 'units','normalized','outerposition',[0 0 0.2 1]);
            title('Label from wrist down to heel')
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            reasonable = reasonable_res(app);
            if  reasonable
                create_res(app);  
            
            
                display("Results are");
                display(app.res);
                auxsave2 = app.res;
                
                append_to_file = 0; % Determines the saving operation
                if append_to_file
                    % Appends to loaded mat file
                    save(app.fullFile, 'auxsave2', '-append');
                    disp('Saved by appending to original file')
                else
                    % Saves in different file
                    [pathName] = fileparts(app.fullFile);   

                    if app.openByScript 
                        % Save automatically
                        fileName = ['labels_' app.info.PatientID '.mat'];
                    else
                        % Save using GUI
                        [fileName, pathName] = uiputfile({'*.mat;';},'Select a file',[pathName filesep ['labels_' app.info.PatientID '.mat']]);
                    end

                    
                    if isequal(fileName,0) || isequal(pathName,0)
                       uialert(app.UIFigure,'Canceled saving.','Saving Warning', 'Icon','warning');
                    else
                       save(fullfile(pathName, fileName), 'auxsave2');
                       disp(['Saved in ', fullfile(pathName, fileName)])
                       
                       if app.openByScript 
                           % Closes the App, so it can be reopened
                           delete(app)
                       end
                    end
                end
            else
                % Warning from reasonable will be displayed
                % Doesn't save or close
            end
        end

        % Button pushed function: ResetLinesButton
        function ResetLinesButtonPushed(app, event)
            % Removes all lines and their indexes
            app.k = 0;
            app.sliceIdx =  zeros(1,app.n);
            if ~isempty(app.p)
                delete(app.p);
                app.p = [];
            end
            display("Deleted Lines");
        end

        % Button pushed function: AddLinesButton
        function AddLinesButtonPushed(app, event)
            app.p = zeros(1,app.n);
    
            hold on
            for i=1:app.n
                app.p(i) = plot([0 0], [0,0], 'g-');
            end
            hold off
        
            app.k = 0;
            app.sliceIdx = zeros(1,app.n);
            
            set(app.f1, 'WindowScrollWheelFcn', @wheel)
            set(app.f1, 'WindowButtonDownFcn', @click)
            
            % Changes slice1 once mouse is moved, calls re_eval1
            function wheel(src, evnt)
                cur=gcf;
                switch cur
                    case 1
                        if evnt.VerticalScrollCount > 0
                            app.slice1=app.slice1+1;
                        else
                            app.slice1=app.slice1-1;
                        end
                        re_eval1(app)
                    otherwise
                        % no change
                end
            end
        
            % Left mouse click triggers function
            % Position of mouse will be captures, horz line plotted, slice number stored
            function click(src,evnt)
                app.k = app.k+1;
                cP = get(gca, 'CurrentPoint');
                
                if app.k>app.n 
                    uialert(app.UIFigure,'Already drew n lines. \n Reset lines, save or change n in code view.','Warning', 'Icon','warning');
                    %display('Already drew n lines.');
                elseif ~on_image(app,cP)
                    app.k = app.k-1;
                    return
                else
                    set(app.p(app.k), 'XData', [1 (size(app.img,2)-1)*app.scaling]);
                    set(app.p(app.k), 'YData', [cP(1,2) cP(1,2)]);
                    app.sliceIdx(app.k) = ceil(size(app.img,3)-cP(1,2)/app.scaling);
                end
            end  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.AddLinesButton = uibutton(app.Panel, 'push');
            app.AddLinesButton.ButtonPushedFcn = createCallbackFcn(app, @AddLinesButtonPushed, true);
            app.AddLinesButton.Text = 'Add Lines';
            app.LoadNewPatientButton = uibutton(app.Panel, 'push');
            app.LoadNewPatientButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewPatientButtonPushed, true);
            app.LoadNewPatientButton.Text = 'Load New Patient';
            app.ResetLinesButton = uibutton(app.Panel, 'push');
            app.ResetLinesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLinesButtonPushed, true);
            app.ResetLinesButton.Text = 'Reset Lines';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.CurrentPatientIDLabel = uilabel(app.Panel);
            app.CurrentPatientIDLabel.Text = 'Current Patient ID';
            app.CurrentPatientLabel = uilabel(app.Panel);
            app.CurrentPatientLabel.Text = 'Current Patient: ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CoronalLabelGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end